classdef SelfBalance_Controller < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SelfBalancingControllerUIFigure  matlab.ui.Figure
        Button_5                  matlab.ui.control.Button
        PositionPanel             matlab.ui.container.Panel
        HoldPositionModeCheckBox  matlab.ui.control.CheckBox
        PositionModeButton        matlab.ui.control.Button
        GOButton                  matlab.ui.control.Button
        DistancecmEditField       matlab.ui.control.NumericEditField
        DistancecmLabel           matlab.ui.control.Label
        Lamp                      matlab.ui.control.Lamp
        ConnectBluetoothButton    matlab.ui.control.Button
        Button_4                  matlab.ui.control.Button
        Button_2                  matlab.ui.control.Button
        Button_3                  matlab.ui.control.Button
        Button                    matlab.ui.control.Button
    end


    properties (Access = private)
        bt % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnectBluetoothButton
        function ConnectBluetoothButtonPushed(app, event)
            instrhwinfo('Bluetooth','HC-05');
            app.bt = Bluetooth('HC-05', 1);
            fopen(app.bt);
            app.ConnectBluetoothButton.Icon = 'E:\sbu\Bachelor_Proj\matlab_codes\Robotprogram\4879873_app_bluetooth_device_mobile_searching_icon.png';
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            a=2;
            fprintf(app.bt,'%d',a);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            c=5;
            fprintf(app.bt,'%d',c);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            d=6;
            fprintf(app.bt,'%d',d);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            b=1;
            fprintf(app.bt,'%d',b);
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
            fprintf(app.bt,'%d',app.DistancecmEditField.Value);
            fprintf(app.bt,'');
        end

        % Button pushed function: PositionModeButton
        function PositionModeButtonPushed(app, event)
            f=3;
            fprintf(app.bt,'%d',f);
            app.Lamp.Color = 'red';
            pause(5);
            app.Lamp.Color = [0.80,0.80,0.80];
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            e=0;
            fprintf(app.bt,'%d',e);
        end

        % Value changed function: HoldPositionModeCheckBox
        function HoldPositionModeCheckBoxValueChanged(app, event)
            value = app.HoldPositionModeCheckBox.Value;
            if value==1
                t=4;
                fprintf(app.bt,'%d',t);
            else
                j=0;
                fprintf(app.bt,'%d',j);    
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SelfBalancingControllerUIFigure = uifigure('Visible', 'off');
            app.SelfBalancingControllerUIFigure.Name = 'Self Balancing Controller';
            app.SelfBalancingControllerUIFigure.Icon = '4026450_device_electronic_machine_robot_technology_icon.png';
            app.Button = uibutton(app.SelfBalancingControllerUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = '4781842_arrow_chevron_direction_down_move_icon.png';
            app.Button.Text = '';
            app.Button_3 = uibutton(app.SelfBalancingControllerUIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = '4781844_arrow_back_chevron_direction_left_icon.png';
            app.Button_3.Text = '';
            app.Button_2 = uibutton(app.SelfBalancingControllerUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = '4781843_arrows_chevron_direction_left_move_icon.png';
            app.Button_2.Text = '';
            app.Button_4 = uibutton(app.SelfBalancingControllerUIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = '4781845_arrows_bottom_chevron_direction_move_icon.png';
            app.Button_4.Text = '';
            app.ConnectBluetoothButton = uibutton(app.SelfBalancingControllerUIFigure, 'push');
            app.ConnectBluetoothButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectBluetoothButtonPushed, true);
            app.ConnectBluetoothButton.Icon = '4879869_bluetooth_connection_web_wireless_icon.png';
            app.ConnectBluetoothButton.Text = 'Connect Bluetooth';
            app.PositionPanel = uipanel(app.SelfBalancingControllerUIFigure);
            app.PositionPanel.Title = 'Position Panel';
            app.Lamp = uilamp(app.PositionPanel);
            app.DistancecmLabel = uilabel(app.PositionPanel);
            app.DistancecmLabel.Text = 'Distance(cm) :';
            app.DistancecmEditField = uieditfield(app.PositionPanel, 'numeric');
            app.DistancecmEditField.Limits = [-100 100];
            app.DistancecmEditField.RoundFractionalValues = 'on';
            app.GOButton = uibutton(app.PositionPanel, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO !';
            app.PositionModeButton = uibutton(app.PositionPanel, 'push');
            app.PositionModeButton.ButtonPushedFcn = createCallbackFcn(app, @PositionModeButtonPushed, true);
            app.PositionModeButton.Text = 'Position Mode';
            app.HoldPositionModeCheckBox = uicheckbox(app.PositionPanel);
            app.HoldPositionModeCheckBox.ValueChangedFcn = createCallbackFcn(app, @HoldPositionModeCheckBoxValueChanged, true);
            app.HoldPositionModeCheckBox.Text = 'Hold Position Mode';
            app.Button_5 = uibutton(app.SelfBalancingControllerUIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Icon = '345387_critical_error_halt_stop_icon.png';
            app.Button_5.Text = '';
            % Show the figure after all components are created
            app.SelfBalancingControllerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SelfBalance_Controller

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SelfBalancingControllerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SelfBalancingControllerUIFigure)
        end
    end
end